classdef termo < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        IRRefImageUI                   matlab.ui.control.Image
        ChoosereferenceIRimageButton   matlab.ui.control.Button
        VISRefImageUI                  matlab.ui.control.Image
        ChoosereferenceVISimageButton  matlab.ui.control.Button
        RegisterImageButton            matlab.ui.control.Button
        RegistrationResultUI           matlab.ui.control.UIAxes
        IRImageisfixedCheckBox         matlab.ui.control.CheckBox
        ManualmodeCheckBox             matlab.ui.control.CheckBox
    end

    
    properties (Access = public)
        RegistrationTransform % Description
        VISRefImage, IRRefImage, % Description
        FixedIR
    end
    
    methods (Access = private)
        function validated = validateIRVISImages(app)
            validated = ~isempty(app.IRRefImage) && ~isempty(app.VISRefImage);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ChoosereferenceIRimageButton
        function ChoosereferenceIRimageButtonPushed(app, event)
            [file,path] = uigetfile('*.png', 'Select a Reference IR Image File');
            app.IRRefImageUI.ImageSource = fullfile(path,file);
            app.IRRefImage = imread(fullfile(path,file));
            
            app.RegisterImageButton.Enable = app.validateIRVISImages();
            
            rgb = app.IRRefImage;
            
            [centers, radii] = imfindcircles(rgb,[5 200]);

        end

        % Button pushed function: ChoosereferenceVISimageButton
        function ChoosereferenceVISimageButtonPushed(app, event)
            [file,path] = uigetfile('*.jpg', 'Select a Reference VIS Image File');
            app.VISRefImageUI.ImageSource = fullfile(path,file);
            img = imread(fullfile(path,file));
            
            img = imresize(img, 0.125);
            app.VISRefImage = img;
            
            app.RegisterImageButton.Enable = app.validateIRVISImages();
            
%             [centers, radii] = imfindcircles(rgb,[5 100], 'ObjectPolarity','dark', 'Sensitivity',0.7);
            
%             imshow(rgb);
%             f = gca;
%             f.Visible = 'On';
%             h = viscircles(centers,radii);
        end

        % Button pushed function: RegisterImageButton
        function RegisterImageButtonPushed(app, event)
            
            app.FixedIR = app.IRImageisfixedCheckBox.Value;
%             if(app.FixedIR)
%                 fixed = app.IRRefImage; % fixed = root image
%                 moving = app.VISRefImage;
%                 %%%% only testing purposes start
%                 points = load('fixedIRPoints.mat');
%                 %%%% only testing purposes end        
%             else 
%                 fixed = app.VISRefImage; % fixed = root image
%                 moving = app.IRRefImage;
%                 %%%% only testing purposes start
%                 points = load('fixedVISPoints.mat');
%                 %%%% only testing purposes end    
%             end
%             %%%% only testing purposes start
%             movingPoints = points.fixedPoints;
%             fixedPoints = points.movingPoints;
%             %%%% only testing purposes end
%             
%             % comment this for testing purposes
% %             [fixedPoints, movingPoints] = cpselect(fixed, moving,'Wait',true);
            [IRPoints, radii] = imfindcircles(app.IRRefImage, [5 200]);
%             h = viscircles(IRPoints,radii);
            [VISPoints, radii] = imfindcircles(app.VISRefImage, [5 100], 'ObjectPolarity','dark', 'Sensitivity',0.7);
%             figure;
%             h = viscircles(VISPoints,radii);
%                         figure;

            IRPoints = sortrows(IRPoints);
            VISPoints = sortrows(VISPoints);
            
            if(app.ManualmodeCheckBox.Value)
                [IRPoints, VISPoints] = cpselect(app.IRRefImage, app.VISRefImage,'Wait',true);
            end
                
            if(app.FixedIR)
                fixed = app.IRRefImage; % fixed = root image
                moving = app.VISRefImage;
                
                fixedPoints = IRPoints;
                movingPoints = VISPoints;
            else 
                fixed = app.VISRefImage;
                moving = app.IRRefImage;
                
                fixedPoints = VISPoints;
                movingPoints = IRPoints;
            end

            tform = fitgeotrans(movingPoints, fixedPoints, 'projective');
            movingRegistered = imwarp(moving, tform, 'OutputView', imref2d(size(fixed)));

            imshowpair(fixed, movingRegistered, 'Parent', app.RegistrationResultUI);            
            app.RegistrationResultUI.Title.String = 'Registration result';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.IRRefImageUI = uiimage(app.GridLayout);
            app.ChoosereferenceIRimageButton = uibutton(app.GridLayout, 'push');
            app.ChoosereferenceIRimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosereferenceIRimageButtonPushed, true);
            app.ChoosereferenceIRimageButton.Text = 'Choose reference IR image';
            app.VISRefImageUI = uiimage(app.GridLayout);
            app.ChoosereferenceVISimageButton = uibutton(app.GridLayout, 'push');
            app.ChoosereferenceVISimageButton.ButtonPushedFcn = createCallbackFcn(app, @ChoosereferenceVISimageButtonPushed, true);
            app.ChoosereferenceVISimageButton.Text = 'Choose reference VIS image';
            app.RegisterImageButton = uibutton(app.GridLayout, 'push');
            app.RegisterImageButton.ButtonPushedFcn = createCallbackFcn(app, @RegisterImageButtonPushed, true);
            app.RegisterImageButton.Text = 'Register Image';
            app.RegistrationResultUI = uiaxes(app.GridLayout);
            title(app.RegistrationResultUI, 'Title')
            xlabel(app.RegistrationResultUI, '')
            ylabel(app.RegistrationResultUI, '')
            app.IRImageisfixedCheckBox = uicheckbox(app.GridLayout);
            app.IRImageisfixedCheckBox.Text = 'IR Image is fixed';
            app.IRImageisfixedCheckBox.Value = true;
            app.ManualmodeCheckBox = uicheckbox(app.GridLayout);
            app.ManualmodeCheckBox.Text = 'Manual mode';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = termo

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end